classdef DELLO_Input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InputInfor                  matlab.ui.Figure
        T1CTCoregMethodButtonGroup  matlab.ui.container.ButtonGroup
        spm_coregButton             matlab.ui.control.RadioButton
        ants_registrationButton     matlab.ui.control.RadioButton
        ImportButton                matlab.ui.control.Button
        NextButton                  matlab.ui.control.Button
        SelectButtonCT              matlab.ui.control.Button
        SelectButtonMRI             matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        InputElectrodesInformationLabel  matlab.ui.control.Label
        InputFilesLabel             matlab.ui.control.Label
        InputInformationLabel       matlab.ui.control.Label
        postsurgCTEditField         matlab.ui.control.EditField
        postsurgCTEditFieldLabel    matlab.ui.control.Label
        AnatomyEditField            matlab.ui.control.EditField
        AnatomyEditFieldLabel       matlab.ui.control.Label
    end


    properties (Access = public)
        MRIFile % 
        CTFile
        ElectrodeInfo
        CoregMethod        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            InitialData = cell(30,2);
            InitialData(1:3,1) = {'A','B','C'};
            InitialData(1:3,2) = {'12','12','12'};
            app.UITable.Data = InitialData;
            app.UITable.ColumnEditable = true;
            app.InputInfor.WindowStyle = "alwaysontop";
            uiwait(app.InputInfor)            
        end

        % Button pushed function: SelectButtonMRI
        function SelectButtonMRIPushed(app, event)
            [file,path] = uigetfile('*.nii');
            app.MRIFile = [path,file];
            app.AnatomyEditField.Value = app.MRIFile;
            % app.InputInfor.Visible = 'Off';
            % app.InputInfor.Visible = 'On';
        end

        % Button pushed function: SelectButtonCT
        function SelectButtonCTPushed(app, event)
            [file,path] = uigetfile('*.nii');
            app.CTFile = [path,file];
            app.postsurgCTEditField.Value = app.CTFile;
            % app.InputInfor.Visible = 'Off';
            % app.InputInfor.Visible = 'On';
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.ElectrodeInfo = app.UITable.Data;
            selectedButton = app.T1CTCoregMethodButtonGroup.SelectedObject;
            disp(['T1&CT Coreg Method: ' selectedButton.Text]);
            app.CoregMethod = selectedButton.Text;
            uiresume(app.InputInfor)
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file,path] = uigetfile('*.csv');
            ElecInforFile = [path,file];
            
            T = importdata(ElecInforFile);
            celldata = [T.textdata,num2cell(T.data)];
            app.UITable.Data = celldata;
            % app.InputInfor.Visible = 'Off';
            % app.InputInfor.Visible = 'On';
        end

        % Selection changed function: T1CTCoregMethodButtonGroup
        function T1CTCoregMethodButtonGroupSelectionChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InputInfor = uifigure('Visible', 'off');
            app.InputInfor.Name = 'UI Figure';
            app.AnatomyEditFieldLabel = uilabel(app.InputInfor);
            app.AnatomyEditFieldLabel.Text = 'Anatomy';
            app.AnatomyEditField = uieditfield(app.InputInfor, 'text');
            app.postsurgCTEditFieldLabel = uilabel(app.InputInfor);
            app.postsurgCTEditFieldLabel.Text = 'post-surg. CT';
            app.postsurgCTEditField = uieditfield(app.InputInfor, 'text');
            app.InputInformationLabel = uilabel(app.InputInfor);
            app.InputInformationLabel.Text = 'Input Information';
            app.InputFilesLabel = uilabel(app.InputInfor);
            app.InputFilesLabel.Text = 'Input Files';
            app.InputElectrodesInformationLabel = uilabel(app.InputInfor);
            app.InputElectrodesInformationLabel.Text = 'Input Electrodes Information';
            app.UITable = uitable(app.InputInfor);
            app.UITable.ColumnName = {'Electrode Names'; 'Number of Channels'};
            app.UITable.RowName = {};
            app.SelectButtonMRI = uibutton(app.InputInfor, 'push');
            app.SelectButtonMRI.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonMRIPushed, true);
            app.SelectButtonMRI.Text = 'Select';
            app.SelectButtonCT = uibutton(app.InputInfor, 'push');
            app.SelectButtonCT.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonCTPushed, true);
            app.SelectButtonCT.Text = 'Select';
            app.NextButton = uibutton(app.InputInfor, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ImportButton = uibutton(app.InputInfor, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.T1CTCoregMethodButtonGroup = uibuttongroup(app.InputInfor);
            app.T1CTCoregMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @T1CTCoregMethodButtonGroupSelectionChanged, true);
            app.T1CTCoregMethodButtonGroup.Title = 'T1&CT Coreg Method';
            app.ants_registrationButton = uiradiobutton(app.T1CTCoregMethodButtonGroup);
            app.ants_registrationButton.Text = 'ants_registration';
            app.ants_registrationButton.Value = true;
            app.spm_coregButton = uiradiobutton(app.T1CTCoregMethodButtonGroup);
            app.spm_coregButton.Text = 'spm_coreg';
            % Show the figure after all components are created
            app.InputInfor.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DELLO_Input

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InputInfor)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InputInfor)
        end
    end
end